classdef StatsTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatsToolsUIFigure       matlab.ui.Figure
        StatsDisplay             matlab.ui.control.UIAxes
        XButton                  matlab.ui.control.Button
        YButton                  matlab.ui.control.Button
        ZButton                  matlab.ui.control.Button
        NextButton               matlab.ui.control.Button
        PrevButton               matlab.ui.control.Button
        DataIndexEditField       matlab.ui.control.NumericEditField
        TabGroup                 matlab.ui.container.TabGroup
        Classical2ptTab          matlab.ui.container.Tab
        CutoffLabel              matlab.ui.control.Label
        CutoffEditField          matlab.ui.control.NumericEditField
        BoundaryDropDownLabel    matlab.ui.control.Label
        BoundaryDropDown         matlab.ui.control.DropDown
        SetLabel                 matlab.ui.control.Label
        SetDropDown              matlab.ui.control.DropDown
        ComputeButton            matlab.ui.control.Button
        AngleInvariant2ptTab     matlab.ui.container.Tab
        CutoffEditField_3Label   matlab.ui.control.Label
        CutoffEditField_3        matlab.ui.control.NumericEditField
        ComputeButton_3          matlab.ui.control.Button
        SetDropDown_3Label       matlab.ui.control.Label
        SetDropDown_3            matlab.ui.control.DropDown
        BoundaryDropDown_3Label  matlab.ui.control.Label
        BoundaryDropDown_3       matlab.ui.control.DropDown
        Pearson2ptTab            matlab.ui.container.Tab
        CutoffEditField_2Label   matlab.ui.control.Label
        CutoffEditField_2        matlab.ui.control.NumericEditField
        ComputeButton_2          matlab.ui.control.Button
        SetDropDown_2Label       matlab.ui.control.Label
        SetDropDown_2            matlab.ui.control.DropDown
        BoundaryDropDown_2Label  matlab.ui.control.Label
        BoundaryDropDown_2       matlab.ui.control.DropDown
        Panel                    matlab.ui.container.Panel
        OpenButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        DataIndexLabel           matlab.ui.control.Label
        FigureButton             matlab.ui.control.Button
    end


    properties (Access = private)
        LoadedImage = [];
        LoadMode = [];
        
        ComputedStats = [];
        SampleStats = [];
        Cutoff = [];
        ColorMap = [];
        StatMode = [];
        
        RotXX = [];
        RotYY = [];
        AngleBinWidth = 3;
    end

    methods (Access = private)
    
        function updatedisplay(app)
            switch app.StatMode
                case 'Regular'
                    if ismatrix(app.SampleStats)
                        imagesc(app.StatsDisplay,app.SampleStats);
                        app.XButton.Enable = 'off';
                        app.YButton.Enable = 'off';
                        app.ZButton.Enable = 'off'; 
                        app.XButton.BackgroundColor = [0.94 0.94 0.94];
                        app.YButton.BackgroundColor = [0.94 0.94 0.94];
                        app.ZButton.BackgroundColor = [0.94 0.94 0.94];
                        
                    elseif ndims(app.SampleStats) == 3
                        imagesc(app.StatsDisplay,app.SampleStats(:,:,floor(size(app.SampleStats,3)/2)+1));
                        app.XButton.Enable = 'on';
                        app.YButton.Enable = 'on';
                        app.ZButton.Enable = 'on';
                        app.XButton.BackgroundColor = [0.94 0.94 0.94];
                        app.YButton.BackgroundColor = [0.94 0.94 0.94];
                        app.ZButton.BackgroundColor = [0.93 0.69 0.13];
                        
                    end
                    
                case 'Pearson'
                    if ismatrix(app.SampleStats)
                        imagesc(app.StatsDisplay,app.SampleStats);
                        app.XButton.Enable = 'off';
                        app.YButton.Enable = 'off';
                        app.ZButton.Enable = 'off'; 
                        app.XButton.BackgroundColor = [0.94 0.94 0.94];
                        app.YButton.BackgroundColor = [0.94 0.94 0.94];
                        app.ZButton.BackgroundColor = [0.94 0.94 0.94];
                        
                    elseif ndims(app.SampleStats) == 3
                        imagesc(app.StatsDisplay,app.SampleStats(:,:,floor(size(app.SampleStats,3)/2)+1));
                        app.XButton.Enable = 'on';
                        app.YButton.Enable = 'on';
                        app.ZButton.Enable = 'on';
                        app.XButton.BackgroundColor = [0.94 0.94 0.94];
                        app.YButton.BackgroundColor = [0.94 0.94 0.94];
                        app.ZButton.BackgroundColor = [0.93 0.69 0.13];
                        
                    end
                    
                case 'Rotational'
                    if ismatrix(app.SampleStats)
                        app.XButton.Enable = 'off';
                        app.YButton.Enable = 'off';
                        app.ZButton.Enable = 'off'; 
                        app.XButton.BackgroundColor = [0.94 0.94 0.94];
                        app.YButton.BackgroundColor = [0.94 0.94 0.94];
                        app.ZButton.BackgroundColor = [0.94 0.94 0.94];
                    end
                    
                    drawRot(app,app.StatsDisplay);
                    
            end
            
            switch app.LoadMode
                case 'single'
                    app.NextButton.Enable = 'off';
                    app.PrevButton.Enable = 'off';
                    app.DataIndexEditField.Enable = 'off'; 
                    app.DataIndexLabel.Enable = 'off';
                case 'batch'
                    app.NextButton.Enable = 'on';
                    app.PrevButton.Enable = 'on';
                    app.DataIndexEditField.Enable = 'on';
                    app.DataIndexLabel.Enable = 'on';
                    app.DataIndexEditField.Limits(2) = length(app.LoadedImage);
            end
            
            axis(app.StatsDisplay, 'image');
            axis(app.StatsDisplay, 'off');
            colorbar(app.StatsDisplay,'northoutside');

            colormap(app.StatsDisplay,app.ColorMap);
            
        end
        
        function drawRot(app,targetaxes)
            xx = app.RotXX;
            yy = app.RotYY;
            rotstats = reshape(app.SampleStats,size(xx));   
            anglebinwidth = app.AngleBinWidth;
                           
            surf(targetaxes,[xx xx(:,1)],[yy yy(:,1)],[rotstats rotstats(:,1)]);
            axis(targetaxes,'image');
            shading(targetaxes,'interp');
            axis(targetaxes,'off');
            view(targetaxes,2);
            set(targetaxes,'FontSize',20);
    
            hold(targetaxes,'on');
    
            if targetaxes~=app.StatsDisplay
                plot3(targetaxes,[-max(xx(:))-20 -max(xx(:))-20],[0 max(xx(:))],[1 1],'k','LineWidth',2)
                [axisx, axisy] = pol2cart((0:anglebinwidth:360)*pi/180,repelem(max(xx(:)),size(xx,2)+1));
                plot3(targetaxes,axisx,axisy,ones(length(axisx),1),'k','LineWidth',2);
        
                text(targetaxes,-max(xx(:))-20-5,0,1,'0','FontSize',16,'HorizontalAlignment','right')
                text(targetaxes,-max(xx(:))-20-5,max(xx(:)),1,int2str(max(xx(:))),'FontSize',16,'HorizontalAlignment','right')

                text(targetaxes,-max(xx(:))-1,0,1,'180','FontSize',16,'HorizontalAlignment','right')
                text(targetaxes,max(xx(:))+1,0,1,'0','FontSize',16,'HorizontalAlignment','left')
                text(targetaxes,0,-max(xx(:))-4,1,'270','FontSize',16,'HorizontalAlignment','center')
                text(targetaxes,0,max(xx(:))+4,1,'90','FontSize',16,'HorizontalAlignment','center')
    
                for ii = 1:floor(max(xx(:))/100)
                    [axisx, axisy] = pol2cart((0:anglebinwidth:360)*pi/180,repelem(ii*100,size(xx,2)+1));
                    plot3(targetaxes,axisx,axisy,ones(length(axisx),1),'--k','LineWidth',1);
                    text(targetaxes,-max(xx(:))-50-5,ii*100,1,num2str(ii*100),'FontSize',16,'HorizontalAlignment','right')
                    plot3(targetaxes,[-max(xx(:))-50 -max(xx(:))-20],[ii*100-2 ii*100-2],[1 1],'--k','LineWidth',1)
                end
                
            end
            hold(targetaxes,'off');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screensize = get( groot, 'Screensize' );
            app.StatsToolsUIFigure.Position(1) = screensize(3) - app.StatsToolsUIFigure.Position(3) - 100;
            app.StatsToolsUIFigure.Position(2) = screensize(4) - app.StatsToolsUIFigure.Position(4) - 300;
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            switch app.BoundaryDropDown.Value
                case 'Periodic'
                    periodicity = 'periodic';
                case 'Non-Periodic'    
                    periodicity = 'nonperiodic';
            end
            
            cutoff = app.CutoffEditField.Value;
            app.Cutoff = cutoff;
            
            switch app.LoadMode
                case 'single'
                    currentImage = double(app.LoadedImage);
                    [Counts,Edges] = histcounts(currentImage(:),'BinMethod','integers');
                    Edges(end) = [];
                    States = Edges+0.5;
                    States = States(Counts~=0);
                    switch app.SetDropDown.Value
                        case 'Binary'
                            GG = TwoPoint('auto',cutoff,periodicity, currentImage == 1);
                        case 'Full Column'  
                            GG = zeros((2*cutoff-1)^ndims(currentImage),length(States)-1);
                            for ii = 1:length(States)-1
                                current = TwoPoint('cross',cutoff,periodicity, currentImage == States(1), currentImage == States(ii));
                                GG(:,ii) = current(:);
                            end
                    end
                    app.ComputedStats = GG(:);
                    app.SampleStats = reshape(app.ComputedStats(1:(2*cutoff-1)^ndims(currentImage)),repmat((2*cutoff-1),[1 ndims(currentImage)]));
                    
                case 'batch'
                    app.ComputedStats = cell(length(app.LoadedImage),1);
                    for jj = 1:length(app.LoadedImage)
                        currentImage = double(app.LoadedImage{jj});
                        [Counts,Edges] = histcounts(currentImage(:),'BinMethod','integers');
                        Edges(end) = [];
                        States = Edges+0.5;
                        States = States(Counts~=0);
                        switch app.SetDropDown.Value
                            case 'Binary'
                                GG = TwoPoint('auto',cutoff,periodicity, currentImage == 1);
                            case 'Full Column'  
                                GG = zeros((2*cutoff-1)^ndims(currentImage),length(States)-1);
                                for ii = 1:length(States)-1
                                    current = TwoPoint('cross',cutoff,periodicity, currentImage == States(1), currentImage == States(ii));
                                    GG(:,ii) = current(:);
                                end
                        end
                        app.ComputedStats{jj} = GG(:);
                    end
                    app.SampleStats = reshape(app.ComputedStats{1}(1:(2*cutoff-1)^ndims(currentImage)),repmat((2*cutoff-1),[1 ndims(currentImage)]));
            end
            
            app.StatMode = 'Regular';
            app.ColorMap = 'parula';
            updatedisplay(app)
            
        end

        % Button pushed function: ComputeButton_2
        function ComputeButton_2Pushed(app, event)
            switch app.BoundaryDropDown_2.Value
                case 'Periodic'
                    periodicity = 'periodic';
                case 'Non-Periodic'    
                    periodicity = 'nonperiodic';
            end
            
            cutoff = app.CutoffEditField_2.Value;
            app.Cutoff = cutoff;
            
            switch app.LoadMode
                case 'single'
                    currentImage = double(app.LoadedImage);
                    [Counts,Edges] = histcounts(currentImage(:),'BinMethod','integers');
                    Edges(end) = [];
                    States = Edges+0.5;
                    States = States(Counts~=0);
                    switch app.SetDropDown_2.Value
                        case 'Binary'
                            GG = PearsonCoef('auto',cutoff,periodicity, currentImage == 1);
                        case 'Full Column'  
                            GG = zeros((2*cutoff-1)^ndims(currentImage),length(States)-1);
                            for ii = 1:length(States)-1
                                current = PearsonCoef('cross',cutoff,periodicity, currentImage == States(1), currentImage == States(ii));
                                GG(:,ii) = current(:);
                            end
                    end
                    app.ComputedStats = GG(:);
                    app.SampleStats = reshape(app.ComputedStats(1:(2*cutoff-1)^ndims(currentImage)),repmat((2*cutoff-1),[1 ndims(currentImage)]));
                    
                case 'batch'
                    app.ComputedStats = cell(length(app.LoadedImage),1);
                    for jj = 1:length(app.LoadedImage)
                        currentImage = double(app.LoadedImage{jj});
                        [Counts,Edges] = histcounts(currentImage(:),'BinMethod','integers');
                        Edges(end) = [];
                        States = Edges+0.5;
                        States = States(Counts~=0);
                        switch app.SetDropDown_2.Value
                            case 'Binary'
                                GG = PearsonCoef('auto',cutoff,periodicity, currentImage == 1);
                            case 'Full Column'  
                                GG = zeros((2*cutoff-1)^ndims(currentImage),length(States)-1);
                                for ii = 1:length(States)-1
                                    current = PearsonCoef('cross',cutoff,periodicity, currentImage == States(1), currentImage == States(ii));
                                    GG(:,ii) = current(:);
                                end
                        end
                        app.ComputedStats{jj} = GG(:);
                    end
                    app.SampleStats = reshape(app.ComputedStats{1}(1:(2*cutoff-1)^ndims(currentImage)),repmat((2*cutoff-1),[1 ndims(currentImage)]));
            end
            
            app.StatMode = 'Pearson';
            app.ColorMap = 'parula';
            updatedisplay(app)
            
        end

        % Button pushed function: ComputeButton_3
        function ComputeButton_3Pushed(app, event)
            switch app.BoundaryDropDown_3.Value
                case 'Periodic'
                    periodicity = 'periodic';
                case 'Non-Periodic'    
                    periodicity = 'nonperiodic';
            end
            
            cutoff = app.CutoffEditField_3.Value;
            app.Cutoff = cutoff;
            
            switch app.LoadMode
                case 'single'
                    currentImage = double(app.LoadedImage);
                    [Counts,Edges] = histcounts(currentImage(:),'BinMethod','integers');
                    Edges(end) = [];
                    States = Edges+0.5;
                    States = States(Counts~=0);
                    switch app.SetDropDown_3.Value
                        case 'Binary'
                            GG = TwoPoint('auto',cutoff,periodicity, currentImage == 1);
                            [GG, xx, yy] = RotInv(GG);
                        case 'Full Column'  
                            GG = zeros((2*cutoff-1)^ndims(currentImage),length(States)-1);
                            for ii = 1:length(States)-1
                                current = TwoPoint('cross',cutoff,periodicity, currentImage == States(1), currentImage == States(ii));
                                [current, xx, yy] = RotInv(current);
                                GG(:,ii) = current(:);
                            end
                    end
                    app.ComputedStats = GG(:);
                    app.SampleStats = reshape(app.ComputedStats,size(xx));
                    app.RotXX = xx;
                    app.RotYY = yy;
           
                case 'batch'
                    app.ComputedStats = cell(length(app.LoadedImage),1);
                    for jj = 1:length(app.LoadedImage)
                        currentImage = double(app.LoadedImage{jj});
                        [Counts,Edges] = histcounts(currentImage(:),'BinMethod','integers');
                        Edges(end) = [];
                        States = Edges+0.5;
                        States = States(Counts~=0);
                        switch app.SetDropDown_3.Value
                            case 'Binary'
                                GG = TwoPoint('auto',cutoff,periodicity, currentImage == 1);
                                [GG, xx, yy] = RotInv(GG);
                            case 'Full Column'  
                                GG = zeros((2*cutoff-1)^ndims(currentImage),length(States)-1);
                                for ii = 1:length(States)-1
                                    current = TwoPoint('cross',cutoff,periodicity, currentImage == States(1), currentImage == States(ii));
                                    [current, xx, yy] = RotInv(current);
                                    GG(:,ii) = current(:);
                                end
                        end
                        app.ComputedStats{jj} = GG(:);
                    end
                    app.SampleStats = reshape(app.ComputedStats{1},size(xx));
                    app.RotXX = xx;
                    app.RotYY = yy;
            end
            
            app.StatMode = 'Rotational';
            app.ColorMap = 'Parula';
            updatedisplay(app);
            
        end

        % Value changed function: DataIndexEditField
        function DataIndexEditFieldValueChanged(app, event)
            cutoff = app.Cutoff;
            app.SampleStats = reshape(app.ComputedStats{app.DataIndexEditField.Value}(1:(2*cutoff-1)^ndims(app.SampleStats)),repmat((2*cutoff-1),[1 ndims(app.SampleStats)]));
            updatedisplay(app);
        end

        % Button pushed function: FigureButton
        function FigureButtonPushed(app, event)
            h1 = figure;
            
            if strcmp(app.StatMode,'Rotational')
                drawRot(app,gca);                
            else
                if ismatrix(app.SampleStats)
                    imagesc(gca,app.SampleStats);
                elseif ndims(app.SampleStats) == 3
                    imagesc(gca,app.SampleStats(:,:,floor(size(app.SampleStats,3)/2)+1));
                end
                axis 'image';
            end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.DataIndexEditField.Value < app.DataIndexEditField.Limits(2)
                cutoff = app.Cutoff;
                app.DataIndexEditField.Value = app.DataIndexEditField.Value + 1;
                if strcmp(app.StatMode,'Rotational')
                    app.SampleStat = reshape(app.ComputedStats{app.DataIndexEditField.Value},size(app.SampleStats));
                else
                    app.SampleStats = reshape(app.ComputedStats{app.DataIndexEditField.Value}(1:(2*cutoff-1)^ndims(app.SampleStats)),repmat((2*cutoff-1),[1 ndims(app.SampleStats)]));
                end
                updatedisplay(app);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.OpenButton.Enable = 'off';
            [FileName,PathName,~] = uigetfile('*.mat','Select Segmented Image Dataset','Segmented.mat');
            
            if FileName == 0   
                errordlg('No valid file has been selected!');
                app.OpenButton.Enable = 'on';
                return
            end
            
            FilePath = fullfile(PathName,FileName);
            
            S = uiimport(FilePath);
            
            if isempty(S)
                errordlg('No valid file has been selected!');
                app.OpenButton.Enable = 'on';
                return
            end
            
            Snames = fieldnames(S);
            app.LoadedImage = S.(Snames{1});
            
            if isnumeric(app.LoadedImage) || islogical(app.LoadedImage)
                app.LoadMode = 'single';
                sample = app.LoadedImage;
            elseif iscell(app.LoadedImage)
                app.LoadMode = 'batch';
                sample = app.LoadedImage{1};
            end
             
            app.OpenButton.Enable = 'on';
            
            if ismatrix(sample)
                app.CutoffEditField_3.Enable = 'on';
                app.ComputeButton_3.Enable = 'on';
                app.SetDropDown_3.Enable = 'on';
                app.BoundaryDropDown_3.Enable = 'on';
            elseif ndims(sample) == 3
                app.CutoffEditField_3.Enable = 'off';
                app.ComputeButton_3.Enable = 'off';
                app.SetDropDown_3.Enable = 'off';
                app.BoundaryDropDown_3.Enable = 'off';
            end
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            if app.DataIndexEditField.Value > app.DataIndexEditField.Limits(1)
                cutoff = app.Cutoff;
                app.DataIndexEditField.Value = app.DataIndexEditField.Value - 1;
                if strcmp(app.StatMode,'Rotational')
                    app.SampleStats = reshape(app.ComputedStats{app.DataIndexEditField.Value},size(app.SampleStat));
                else
                    app.SampleStats = reshape(app.ComputedStats{app.DataIndexEditField.Value}(1:(2*cutoff-1)^ndims(app.SampleStats)),repmat((2*cutoff-1),[1 ndims(app.SampleStats)]));
                end
                updatedisplay(app);
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            imagesc(app.StatsDisplay,squeeze(app.SampleStats(floor(size(app.SampleStats,1)/2)+1,:,:)));
            axis(app.StatsDisplay, 'image');
            axis(app.StatsDisplay, 'off');
            app.XButton.BackgroundColor = [0.93 0.69 0.13];
            app.YButton.BackgroundColor = [0.94 0.94 0.94];
            app.ZButton.BackgroundColor = [0.94 0.94 0.94];
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            imagesc(app.StatsDisplay,squeeze(app.SampleStats(:,floor(size(app.SampleStats,2)/2)+1,:)));
            axis(app.StatsDisplay, 'image');
            axis(app.StatsDisplay, 'off');
            app.XButton.BackgroundColor = [0.94 0.94 0.94];
            app.YButton.BackgroundColor = [0.93 0.69 0.13];
            app.ZButton.BackgroundColor = [0.94 0.94 0.94];
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            imagesc(app.StatsDisplay,app.SampleStats(:,:,floor(size(app.SampleStats,3)/2)+1));
            axis(app.StatsDisplay, 'image');
            axis(app.StatsDisplay, 'off');
            app.XButton.BackgroundColor = [0.94 0.94 0.94];
            app.YButton.BackgroundColor = [0.94 0.94 0.94];
            app.ZButton.BackgroundColor = [0.93 0.69 0.13];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            current = app.ComputedStats;
            uisave('current');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StatsToolsUIFigure = uifigure;
            app.StatsToolsUIFigure.Name = 'Stats Tools';
            app.StatsDisplay = uiaxes(app.StatsToolsUIFigure);
            app.StatsDisplay.XTick = [];
            app.StatsDisplay.YTick = [];
            app.XButton = uibutton(app.StatsToolsUIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Enable = 'off';
            app.XButton.Text = 'X';
            app.YButton = uibutton(app.StatsToolsUIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Enable = 'off';
            app.YButton.Text = 'Y';
            app.ZButton = uibutton(app.StatsToolsUIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Enable = 'off';
            app.ZButton.Text = 'Z';
            app.NextButton = uibutton(app.StatsToolsUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'icon_next_frame-512.png';
            app.NextButton.Enable = 'off';
            app.NextButton.Text = '';
            app.PrevButton = uibutton(app.StatsToolsUIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Icon = 'icon_previous_frame-512.png';
            app.PrevButton.Enable = 'off';
            app.PrevButton.Text = '';
            app.DataIndexEditField = uieditfield(app.StatsToolsUIFigure, 'numeric');
            app.DataIndexEditField.ValueChangedFcn = createCallbackFcn(app, @DataIndexEditFieldValueChanged, true);
            app.DataIndexEditField.Limits = [1 Inf];
            app.DataIndexEditField.Enable = 'off';
            app.DataIndexEditField.Value = 1;
            app.TabGroup = uitabgroup(app.StatsToolsUIFigure);
            app.Classical2ptTab = uitab(app.TabGroup);
            app.Classical2ptTab.Title = 'Classical 2-pt';
            app.CutoffLabel = uilabel(app.Classical2ptTab);
            app.CutoffLabel.Text = 'Cutoff:';
            app.CutoffEditField = uieditfield(app.Classical2ptTab, 'numeric');
            app.CutoffEditField.Value = 50;
            app.BoundaryDropDownLabel = uilabel(app.Classical2ptTab);
            app.BoundaryDropDownLabel.Text = 'Boundary:';
            app.BoundaryDropDown = uidropdown(app.Classical2ptTab);
            app.BoundaryDropDown.Items = {'Periodic', 'Non-Periodic'};
            app.BoundaryDropDown.Value = 'Periodic';
            app.SetLabel = uilabel(app.Classical2ptTab);
            app.SetLabel.Text = 'Set:';
            app.SetDropDown = uidropdown(app.Classical2ptTab);
            app.SetDropDown.Items = {'Binary', 'Full Column'};
            app.SetDropDown.Value = 'Binary';
            app.ComputeButton = uibutton(app.Classical2ptTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Icon = 'Media-Controls-Play-icon.png';
            app.ComputeButton.Text = 'Compute';
            app.AngleInvariant2ptTab = uitab(app.TabGroup);
            app.AngleInvariant2ptTab.Title = 'Angle Invariant 2-pt';
            app.CutoffEditField_3Label = uilabel(app.AngleInvariant2ptTab);
            app.CutoffEditField_3Label.Text = 'Cutoff:';
            app.CutoffEditField_3 = uieditfield(app.AngleInvariant2ptTab, 'numeric');
            app.CutoffEditField_3.Value = 50;
            app.ComputeButton_3 = uibutton(app.AngleInvariant2ptTab, 'push');
            app.ComputeButton_3.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_3Pushed, true);
            app.ComputeButton_3.Icon = 'Media-Controls-Play-icon.png';
            app.ComputeButton_3.Text = 'Compute';
            app.SetDropDown_3Label = uilabel(app.AngleInvariant2ptTab);
            app.SetDropDown_3Label.Text = 'Set:';
            app.SetDropDown_3 = uidropdown(app.AngleInvariant2ptTab);
            app.SetDropDown_3.Items = {'Binary', 'Full Column'};
            app.SetDropDown_3.Value = 'Binary';
            app.BoundaryDropDown_3Label = uilabel(app.AngleInvariant2ptTab);
            app.BoundaryDropDown_3Label.Text = 'Boundary:';
            app.BoundaryDropDown_3 = uidropdown(app.AngleInvariant2ptTab);
            app.BoundaryDropDown_3.Items = {'Periodic', 'Non-Periodic'};
            app.BoundaryDropDown_3.Value = 'Periodic';
            app.Pearson2ptTab = uitab(app.TabGroup);
            app.Pearson2ptTab.Title = 'Pearson 2pt';
            app.CutoffEditField_2Label = uilabel(app.Pearson2ptTab);
            app.CutoffEditField_2Label.Text = 'Cutoff:';
            app.CutoffEditField_2 = uieditfield(app.Pearson2ptTab, 'numeric');
            app.CutoffEditField_2.Value = 50;
            app.ComputeButton_2 = uibutton(app.Pearson2ptTab, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_2Pushed, true);
            app.ComputeButton_2.Icon = 'Media-Controls-Play-icon.png';
            app.ComputeButton_2.Text = 'Compute';
            app.SetDropDown_2Label = uilabel(app.Pearson2ptTab);
            app.SetDropDown_2Label.Text = 'Set:';
            app.SetDropDown_2 = uidropdown(app.Pearson2ptTab);
            app.SetDropDown_2.Items = {'Binary', 'Full Column'};
            app.SetDropDown_2.Value = 'Binary';
            app.BoundaryDropDown_2Label = uilabel(app.Pearson2ptTab);
            app.BoundaryDropDown_2Label.Text = 'Boundary:';
            app.BoundaryDropDown_2 = uidropdown(app.Pearson2ptTab);
            app.BoundaryDropDown_2.Items = {'Periodic', 'Non-Periodic'};
            app.BoundaryDropDown_2.Value = 'Periodic';
            app.Panel = uipanel(app.StatsToolsUIFigure);
            app.Panel.Title = 'Panel';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = 'open_folder_blue-512.png';
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Text = 'Save';
            app.DataIndexLabel = uilabel(app.StatsToolsUIFigure);
            app.DataIndexLabel.Enable = 'off';
            app.DataIndexLabel.Text = 'Data Index';
            app.FigureButton = uibutton(app.StatsToolsUIFigure, 'push');
            app.FigureButton.ButtonPushedFcn = createCallbackFcn(app, @FigureButtonPushed, true);
            app.FigureButton.Icon = 'Figure.PNG';
            app.FigureButton.Text = 'Figure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = StatsTool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StatsToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatsToolsUIFigure)
        end
    end
end